classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResetButton                     matlab.ui.control.Button
        CustomVoltagefortestingonlyEditField  matlab.ui.control.NumericEditField
        CustomVoltagefortestingonlyEditFieldLabel  matlab.ui.control.Label
        ReadScaleButton                 matlab.ui.control.Button
        CALIBRATIONINSTRUCTIONSTextArea  matlab.ui.control.TextArea
        OutputVoltageVEditFieldLabel    matlab.ui.control.Label
        CIEditField                     matlab.ui.control.EditField
        CIEditFieldLabel                matlab.ui.control.Label
        MEEditField                     matlab.ui.control.NumericEditField
        MEEditFieldLabel                matlab.ui.control.Label
        StdDevEditField                 matlab.ui.control.NumericEditField
        OutputVoltageVEditField         matlab.ui.control.EditField
        StdDevEditFieldLabel            matlab.ui.control.Label
        TestWeightButton                matlab.ui.control.Button
        CalibrationRatioEditField       matlab.ui.control.NumericEditField
        CalibrationRatioEditFieldLabel  matlab.ui.control.Label
        CalibrationWeightgEditField     matlab.ui.control.EditField
        CalibrationWeightgEditFieldLabel  matlab.ui.control.Label
        JellybeanCountEditField_2       matlab.ui.control.NumericEditField
        JellybeanCountEditField_2Label  matlab.ui.control.Label
        JellybeanCountingScaleEEC1Label  matlab.ui.control.Label
        TareStatusLamp                  matlab.ui.control.Lamp
        TareStatusLampLabel             matlab.ui.control.Label
        CalibrationStatusLamp           matlab.ui.control.Lamp
        CalibrationStatusLampLabel      matlab.ui.control.Label
        WeightgEditField                matlab.ui.control.NumericEditField
        WeightgEditFieldLabel           matlab.ui.control.Label
        TareScaleButton                 matlab.ui.control.Button
        CalibrateScaleButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Calibrate_Pressed = false;
        Tare_Pressed = false;

        current_weight = 0;
        previous_weight = 0;

        current_voltage = 5;
        tared_runoff = 0;

        calibration_weight = 0;
        Calibration_Ratio = 1; 


        %Xavier Code%
        final_voltage = 0;
        voltage_array = [];
        final_std = 0;
        true_weight = 0;
        weight_array = [];
        errors = [];
        mean_error = 0;
        avg_array = [];
        std_array = [];
        manual_voltage = 0;

            %confidence interval props
            n = 0;
            sem;
            t_crit;
            ci_lower;
            ci_upper;

            %calibration array variables
            cali_voltage = [];
            cali_weight = [];
            scale_voltage = [];
            scale_weight = [];
            j = 1;
            k = 1;

            %calculating weight
            reference = 2.5;

    end
    
    methods (Access = private)
        
        
        function results = detect_change(app)

            if(app.current_weight ~= app.previous_weight)
            app.CalibrationStatusLamp.Color = [1.00, 0.00, 0.00] %Change to Red
            app.TareStatusLamp.Color = [1.00, 0.00, 0.00] %Change to Red
            app.Calibrate_Pressed = false;
            app.Tare_Pressed = false;
            end
            
        end
            
        
        function results = update_jbcount(app)
            app.JellybeanCountEditField_2.Value = app.current_weight/1.11;
            app.JellybeanCountEditField_2.Value = floor(app.JellybeanCountEditField_2.Value);
            
        end

        function results = update_weight(app)
            app.previous_weight = app.WeightgEditField.Value;

                %calculate voltage difference to find weight
                volt_diff = abs(app.reference - app.current_voltage);
                disp(app.Calibration_Ratio)
                disp(volt_diff)
                disp(app.reference)
                if mean(volt_diff * abs(app.Calibration_Ratio)) < 0
                app.WeightgEditField.Value = 0;
                else
                app.WeightgEditField.Value = abs(mean(volt_diff * app.Calibration_Ratio));
                end
                app.current_weight = app.WeightgEditField.Value;
            

        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalibrateScaleButton
        function CalibrateScaleButtonPushed(app, event)

                % get new voltage and weight reading
                app.cali_voltage(app.j) = str2double(app.OutputVoltageVEditField.Value);
                app.cali_weight(app.j) = app.calibration_weight;

                %generate graph with line of best fit
                cla(app.UIAxes)
                p = polyfit(app.cali_voltage, app.cali_weight, 1);
                f = polyval(p, app.cali_voltage);
                best_fit = linspace(min(app.cali_voltage), max(app.cali_voltage), app.j);
                h = plot(app.UIAxes, app.cali_voltage, app.cali_weight, 'o', app.cali_voltage, f, '-');

                app.j = app.j + 1;
          
                app.Calibration_Ratio = p(1);
                app.CalibrationRatioEditField.Value = app.Calibration_Ratio;
                app.CalibrationStatusLamp.Color = [0.47,0.67,0.19];
                pause(2);
                app.CalibrationStatusLamp.Color = [1,0,0];
                app.Calibrate_Pressed = true;

        end

        % Button pushed function: TareScaleButton
        function TareScaleButtonPushed(app, event)
             if ~(app.Tare_Pressed)

                 app.TareStatusLamp.Color = [0.47, 0.67, 0.19];
                 app.Tare_Pressed = true;
                 pause(2)
                 app.TareStatusLamp.Color = [1, 0, 0];
                
                 %set a new reference weight
                 app.reference = app.current_voltage;

                 %update values
                 app.WeightgEditField.Value = 0;
                 app.current_weight = 0;
                 app.update_jbcount;

             
            end

        end

        % Value changed function: WeightgEditField
        function WeightgEditFieldValueChanged(app, event)
            value = app.WeightgEditField.Value;
            app.WeightgEditField.Value = 0;
        end

        % Callback function
        function AddBeansButtonPushed(app, event)
            app.update_weight;
            app.detect_change;
            app.update_jbcount;
        end

        % Value changed function: OutputVoltageVEditField
        function OutputVoltageVEditFieldValueChanged(app, event)
            app.current_voltage = app.OutputVoltageVEditField.Value;
            app.current_voltage = str2double(app.current_voltage);
            
        end

        % Value changed function: CalibrationWeightgEditField
        function CalibrationWeightgEditFieldValueChanged(app, event)
            app.calibration_weight = app.CalibrationWeightgEditField.Value;
            app.calibration_weight = str2double(app.calibration_weight);
            app.current_weight = app.calibration_weight;
            
        end

        % Button pushed function: TestWeightButton
        function TestWeightButtonPushed(app, event)

            %use voltage_read for manual testing / scale_read to test load
            %cell
            %[app.voltage_array, app.final_voltage, app.final_std, app.avg_array, app.std_array] = voltage_read(app.manual_voltage);
            [app.voltage_array, app.final_voltage, app.final_std, app.avg_array, app.std_array] = scale_read();
            app.OutputVoltageVEditField.Value = num2str(app.final_voltage);

            %update weight
            app.current_voltage = app.final_voltage;
            app.update_weight;

            %update std
            app.StdDevEditField.Value = app.final_std;

            %update mean error
            app.true_weight = app.calibration_weight;
            app.weight_array = (abs((-1 .* app.voltage_array) + double(mean(app.reference)) ) * app.Calibration_Ratio);
            app.errors = app.weight_array - app.true_weight;
            app.mean_error = mean(app.errors);
            app.MEEditField.Value = app.mean_error;

            %plot voltage
            time_axis = 0.5:0.5:5.0;
            w = plot(app.UIAxes2, time_axis, app.avg_array);

            %plot std dev.
            z = plot(app.UIAxes3, time_axis, app.std_array);

            %give confidence intervals
            app.n = length(app.weight_array);
            app.sem = std(app.errors) / sqrt(app.n);
            app.t_crit = tinv(0.975, app.n-1);
            app.ci_lower = app.mean_error - app.t_crit * app.sem;
            app.ci_upper = app.mean_error + app.t_crit * app.sem;
            app.CIEditField.Value = num2str(app.ci_lower) + " to " + num2str(app.ci_upper);
            
            
        end

        % Callback function
        function OutputVoltageVEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.WeightgEditField.Value = 5;
        end

        % Button pushed function: ReadScaleButton
        function ReadScaleButtonPushed(app, event)
            %[app.voltage_array, app.final_voltage, app.final_std, app.avg_array, app.std_array] = voltage_read(app.manual_voltage);
            [app.voltage_array, app.final_voltage, app.final_std, app.avg_array, app.std_array] = scale_read();
            app.OutputVoltageVEditField.Value = num2str(app.final_voltage);

            %update weight
            app.current_voltage = app.final_voltage;
            app.update_weight;
            app.update_jbcount;

            %update std
            app.StdDevEditField.Value = app.final_std;

            %update mean error
            app.true_weight = app.calibration_weight;
            app.weight_array = abs((double(mean(app.reference)) - app.voltage_array)  .* app.Calibration_Ratio);
            app.errors = app.weight_array - app.true_weight;
            app.mean_error = mean(app.errors);
            app.MEEditField.Value = app.mean_error;

            %plot voltage
            time_axis = 0.5:0.5:5.0;
            w = plot(app.UIAxes2, time_axis, app.avg_array);

            %plot std dev.
            z = plot(app.UIAxes3, time_axis, app.std_array);

            %give confidence intervals
            app.n = length(app.weight_array);
            app.sem = std(app.errors) / sqrt(app.n);
            app.t_crit = tinv(0.975, app.n-1);
            app.ci_lower = app.mean_error - app.t_crit * app.sem;
            app.ci_upper = app.mean_error + app.t_crit * app.sem;
            app.CIEditField.Value = num2str(app.ci_lower) + " to " + num2str(app.ci_upper);

            % get new voltage and weight reading
            app.scale_voltage(app.k) = str2double(app.OutputVoltageVEditField.Value);
            app.scale_weight(app.k) = app.current_weight;

                %generate graph with line of best fit
                cla(app.UIAxes4)
                p = polyfit(app.scale_voltage, app.scale_weight, 1);
                f = polyval(p, app.scale_voltage);
                best_fit = linspace(min(app.scale_voltage), max(app.scale_voltage), app.k);
                h = plot(app.UIAxes4, app.scale_voltage, app.scale_weight, 'o', app.scale_voltage, f, '-');

                app.k = app.k + 1;
        end

        % Value changed function: CustomVoltagefortestingonlyEditField
        function CustomVoltagefortestingonlyEditFieldValueChanged(app, event)
            app.manual_voltage = app.CustomVoltagefortestingonlyEditField.Value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reference = -2.5;
            app.WeightgEditField.Value = 0;
            app.JellybeanCountEditField_2.Value = 0;
            app.OutputVoltageVEditField.Value = num2str(0);
            app.CalibrationRatioEditField.Value = 0;
            app.CalibrationWeightgEditField.Value = num2str(0);
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Calibration: Weight (g) vs. Voltage (V)')
            xlabel(app.UIAxes, 'Voltage')
            ylabel(app.UIAxes, 'Weight')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Voltage (V) vs Time (s)')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Voltages')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Std. Deviation (V) vs Time (s)')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Standard Deviation')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Weight (g) vs Output Voltage (V)')
            xlabel(app.UIAxes4, 'Voltage')
            ylabel(app.UIAxes4, 'Weight')
            zlabel(app.UIAxes4, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.URL = 'https://github.com/d8yu/Jellybean_Counter';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Adobe Express - file.png');
            app.CalibrateScaleButton = uibutton(app.UIFigure, 'push');
            app.CalibrateScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateScaleButtonPushed, true);
            app.CalibrateScaleButton.Text = 'Calibrate Scale';
            app.TareScaleButton = uibutton(app.UIFigure, 'push');
            app.TareScaleButton.ButtonPushedFcn = createCallbackFcn(app, @TareScaleButtonPushed, true);
            app.TareScaleButton.Text = 'Tare Scale';
            app.WeightgEditFieldLabel = uilabel(app.UIFigure);
            app.WeightgEditFieldLabel.Text = 'Weight (g)';
            app.WeightgEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightgEditField.Limits = [0 Inf];
            app.WeightgEditField.ValueChangedFcn = createCallbackFcn(app, @WeightgEditFieldValueChanged, true);
            app.CalibrationStatusLampLabel = uilabel(app.UIFigure);
            app.CalibrationStatusLampLabel.Text = 'Calibration Status';
            app.CalibrationStatusLamp = uilamp(app.UIFigure);
            app.TareStatusLampLabel = uilabel(app.UIFigure);
            app.TareStatusLampLabel.Text = 'Tare Status';
            app.TareStatusLamp = uilamp(app.UIFigure);
            app.JellybeanCountingScaleEEC1Label = uilabel(app.UIFigure);
            app.JellybeanCountingScaleEEC1Label.Text = {'Jellybean Counting Scale'; 'EEC 1'};
            app.JellybeanCountEditField_2Label = uilabel(app.UIFigure);
            app.JellybeanCountEditField_2Label.Text = 'Jellybean Count';
            app.JellybeanCountEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationWeightgEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationWeightgEditFieldLabel.Text = 'Calibration Weight (g)';
            app.CalibrationWeightgEditField = uieditfield(app.UIFigure, 'text');
            app.CalibrationWeightgEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationWeightgEditFieldValueChanged, true);
            app.CalibrationRatioEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationRatioEditFieldLabel.Text = 'Calibration Ratio';
            app.CalibrationRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.TestWeightButton = uibutton(app.UIFigure, 'push');
            app.TestWeightButton.ButtonPushedFcn = createCallbackFcn(app, @TestWeightButtonPushed, true);
            app.TestWeightButton.Text = 'Test Weight';
            app.StdDevEditFieldLabel = uilabel(app.UIFigure);
            app.StdDevEditFieldLabel.Text = 'Std Dev';
            app.OutputVoltageVEditField = uieditfield(app.UIFigure, 'text');
            app.OutputVoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @OutputVoltageVEditFieldValueChanged, true);
            app.StdDevEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEEditFieldLabel = uilabel(app.UIFigure);
            app.MEEditFieldLabel.Text = 'M.E';
            app.MEEditField = uieditfield(app.UIFigure, 'numeric');
            app.CIEditFieldLabel = uilabel(app.UIFigure);
            app.CIEditFieldLabel.Text = '95% C.I';
            app.CIEditField = uieditfield(app.UIFigure, 'text');
            app.OutputVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.OutputVoltageVEditFieldLabel.Text = 'Output Voltage (V)';
            app.CALIBRATIONINSTRUCTIONSTextArea = uitextarea(app.UIFigure);
            app.CALIBRATIONINSTRUCTIONSTextArea.Editable = 'off';
            app.CALIBRATIONINSTRUCTIONSTextArea.Value = {'CALIBRATION INSTRUCTIONS: (1) ENTER KNOWN CALIBRATION WEIGHT FIRST (2) PRESS TEST WEIGHT (3) CALIBRATE SCALE (4) REPEAT FOR MULTIPLE WEIGHTS'; 'NOTE: WEIGHTS DISPLAYED DURING CALIBRATION ARE INACCURATE'};
            app.ReadScaleButton = uibutton(app.UIFigure, 'push');
            app.ReadScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ReadScaleButtonPushed, true);
            app.ReadScaleButton.Text = 'Read Scale';
            app.CustomVoltagefortestingonlyEditFieldLabel = uilabel(app.UIFigure);
            app.CustomVoltagefortestingonlyEditFieldLabel.Text = 'Custom Voltage (for testing only)';
            app.CustomVoltagefortestingonlyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CustomVoltagefortestingonlyEditField.ValueChangedFcn = createCallbackFcn(app, @CustomVoltagefortestingonlyEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end